classdef Sound_Detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        Frecuency1SliderLabel   matlab.ui.control.Label
        Frecuency1Slider        matlab.ui.control.Slider
        SignalPanel             matlab.ui.container.Panel
        CH1AnaButton            matlab.ui.control.StateButton
        TimeKnobLabel           matlab.ui.control.Label
        TimeKnob                matlab.ui.control.DiscreteKnob
        CouplingCH1AnaSwitch    matlab.ui.control.Switch
        CHLabel                 matlab.ui.control.Label
        CH1Knob                 matlab.ui.control.DiscreteKnob
        CH1AnaLabel             matlab.ui.control.Label
        VLabel                  matlab.ui.control.Label
        TLabel                  matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Frecuency2SliderLabel   matlab.ui.control.Label
        Frecuency2Slider        matlab.ui.control.Slider
        FilterTypeListBoxLabel  matlab.ui.control.Label
        FilterTypeListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = private)
         % Serial
         s = serial('COM8')
         baudrate = 115200;
         buffersize = 4096;
         % General parameters
         window = 1008;
         fs = 2000;
         cha_ana
         cha_dig
         chb_ana
         chb_dig
         cha_ana_k = 1;
         chb_ana_k = 1;
         offset_cha = 0;
         offset_chb = 0;
         filtro = fir();
         n = 20;
         f
         f1
         f2
         m = 127;
         FilterType
         
    end   
    
    
    methods (Access = private)
        % Callback para cuando se genera el evento de buffer lleno
        function readSerial(app)
            data = fread(app.s, app.buffersize, 'int8');
            [cha_ana_dat, cha_dig_dat, chb_ana_dat, chb_dig_dat, blocksize] = unpack(app,data);
            
            % Apply scale factor
            cha_ana_dat = double((cha_ana_dat + app.offset_cha)*app.cha_ana_k);
            chb_ana_dat = double((chb_ana_dat + app.offset_chb)*app.chb_ana_k);
            cha_dig_dat = cha_dig_dat * 1000;
            chb_dig_dat = chb_dig_dat * 1000 + 1000;
            
            % Remove DC component if the AC coupling is enable
            if app.CouplingCH1AnaSwitch.Value == 'AC'
                cha_ana_dat = cha_ana_dat - mean(cha_ana_dat) + 2048;
            end
            if app.CouplingCH2AnaSwitch.Value == 'AC'
                chb_ana_dat = chb_ana_dat - mean(chb_ana_dat) + 2048;
            end
            
            % Plot
            addpoints(app.cha_ana, 1:blocksize, cha_ana_dat);
            addpoints(app.chb_ana, 1:blocksize, chb_ana_dat);
            addpoints(app.cha_dig, 1:blocksize, double(cha_dig_dat));
            addpoints(app.chb_dig, 1:blocksize, double(chb_dig_dat));
        end
    
        function initSerial(app)
            app.s.BaudRate = app.baudrate;
            app.s.InputBufferSize = app.buffersize;
            app.s.BytesAvailableFcnCount = app.buffersize;      % Número de bytes en el buffer de entrada para generar evento
            app.s.BytesAvailableFcnMode = 'byte';
            app.s.BytesAvailableFcn = @(~,~)app.readSerial; 
        end
        
        function initAnimatedLines(app)
            % Create animatedlines filtroects
            app.cha_ana = animatedline(app.UIAxes, 'Color',[0.00,0.45,0.74]);
            app.chb_ana = animatedline(app.UIAxes, 'Color',[0.85,0.33,0.10]);
            app.cha_dig = animatedline(app.UIAxes, 'Color',[0.93,0.69,0.13]);
            app.chb_dig = animatedline(app.UIAxes, 'Color',[0.47,0.67,0.19]);
            
            % Turn off the visibility in digitals channels by default
            app.cha_dig.Visible = 'off';
            app.chb_dig.Visible = 'off';
            app.Frecuency2Slider.Enable = 'off';
          
            % Assign maximum numbers of point
            app.cha_ana.MaximumNumPoints = app.window;
            app.chb_ana.MaximumNumPoints = app.window;
            app.cha_dig.MaximumNumPoints = app.window;
            app.chb_dig.MaximumNumPoints = app.window;
            app.cha_ana.AlignVertexCenters = 'on';
        end
    
    function [cha_ana, cha_dig, chb_ana, chb_dig, blocksize] = unpack(app,data)
            databyte = int8(data);

            % Sync the data
            start = find(databyte > 0, 1);
            syncData = databyte(start:end-mod((app.buffersize-start),4)-1);
            
            % Convert to unsigned
            syncData = typecast(syncData, 'uint8');
            
            % Resulting data block size
            blocksize = length(syncData)/4;
            
            byte0 = syncData(1:4:end);
            byte1 = syncData(2:4:end);
            byte2 = syncData(3:4:end);
            byte3 = syncData(4:4:end);
                        
            % Mask for analog channels
            Mask_Ana(1:blocksize,1) = uint8(63);
            
            cha_ana_l = bitand(byte0, Mask_Ana);
            cha_ana_h = bitand(byte1, Mask_Ana);
            chb_ana_l = bitand(byte2, Mask_Ana);
            chb_ana_h = bitand(byte3, Mask_Ana);         
            
            % Analog Channels
            cha_ana = uint16(cha_ana_h)*64 + uint16(cha_ana_l);
            chb_ana = uint16(chb_ana_h)*64 + uint16(chb_ana_l);  
            
            % Mask for digital channels
            Mask_Dig(1:blocksize,1) = uint8(64);
            
            % Digital Channels
            cha_dig = bitand(byte1, Mask_Dig);
            chb_dig = bitand(byte3, Mask_Dig);
    end


        function sendCoeff(app)
            coeff = get.coeff(app.filtro);
            coeff = get.coeff_format(pp.filtro);
            coeff = coeffPacked(app.filtro);
            fwrite(app.s, coeff, 'int8');
        end
        
    
end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initSerial;
            app.initAnimatedLines;
            
            fopen(app.s);    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.s);
            delete(app);
        end

        % Value changed function: TimeKnob
        function TimeKnobValueChanged(app, event)
            value = str2double(app.TimeKnob.Value);
            app.Label.Text = string(value);
            scaleMin = 32;
            scaleMax = 10*value*app.fs + 32;
            app.UIAxes.XLim = [scaleMin, scaleMax];
            app.UIAxes.XTick = linspace(scaleMin,scaleMax,11);
        end

        % Value changed function: CH1Knob
        function CH1KnobValueChanged(app, event)
            value = str2double(app.CH1Knob.Value);
            app.VLabel.Text = string(value) + "V";
            app.cha_ana_k = 0.5/value;
        end

        % Value changed function: CH1AnaButton
        function CH1AnaButtonValueChanged(app, event)
            value = app.CH1AnaButton.Value;
            app.cha_ana.Visible = value;
        end

        % Value changed function: Frecuency1Slider
        function Frecuency1SliderValueChanged(app, event)
            value = app.Frecuency1Slider.Value;
            app.filtro.f1 = value;
            app.filtro.f = app.filtro.f1;
        end

        % Value changed function: Frecuency2Slider
        function Frecuency2SliderValueChanged(app, event)
            value = app.Frecuency2Slider.Value;
            app.filtro.f2 = value;
        end

        % Value changed function: FilterTypeListBox
        function FilterTypeListBoxValueChanged(app, event)
            value = app.FilterTypeListBox.Value;
            app.filtro.FilterType = value;
            switch value
                case 'lowpass'
                 app.Frecuency2Slider.Enable = 'off'; 
                case 'highpass'
                  app.Frecuency2Slider.Enable = 'off';
                case 'bandpass'
                  app.Frecuency2Slider.Enable = 'on';
                case 'stopband'
                  app.Frecuency2Slider.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [32 1032];
            app.UIAxes.YLim = [0 4095];
            app.UIAxes.XTick = [1 104.2 207.4 310.6 413.8 517 620.2 723.4 826.6 929.8];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 409.5 819 1228.5 1638 2047.5 2457 2866.5 3276 3685.5 4095];
            app.UIAxes.YTickLabel = '';
            app.Frecuency1SliderLabel = uilabel(app.UIFigure);
            app.Frecuency1SliderLabel.Text = 'Frecuency1';
            app.Frecuency1Slider = uislider(app.UIFigure);
            app.Frecuency1Slider.ValueChangedFcn = createCallbackFcn(app, @Frecuency1SliderValueChanged, true);
            app.SignalPanel = uipanel(app.UIFigure);
            app.SignalPanel.Title = 'Signal';
            app.CH1AnaButton = uibutton(app.SignalPanel, 'state');
            app.CH1AnaButton.ValueChangedFcn = createCallbackFcn(app, @CH1AnaButtonValueChanged, true);
            app.CH1AnaButton.Text = {'ON/OFF'; ''};
            app.CH1AnaButton.Value = true;
            app.TimeKnobLabel = uilabel(app.SignalPanel);
            app.TimeKnobLabel.Text = 'Time';
            app.TimeKnob = uiknob(app.SignalPanel, 'discrete');
            app.TimeKnob.Items = {'0.005', '0.01', '0.05'};
            app.TimeKnob.ValueChangedFcn = createCallbackFcn(app, @TimeKnobValueChanged, true);
            app.TimeKnob.Value = '0.05';
            app.CouplingCH1AnaSwitch = uiswitch(app.SignalPanel, 'slider');
            app.CouplingCH1AnaSwitch.Items = {'AC', 'DC'};
            app.CouplingCH1AnaSwitch.Value = 'DC';
            app.CHLabel = uilabel(app.SignalPanel);
            app.CHLabel.Text = 'CH';
            app.CH1Knob = uiknob(app.SignalPanel, 'discrete');
            app.CH1Knob.Items = {'0.1', '0.25', '0.5', '1'};
            app.CH1Knob.ValueChangedFcn = createCallbackFcn(app, @CH1KnobValueChanged, true);
            app.CH1Knob.Value = '0.5';
            app.CH1AnaLabel = uilabel(app.UIFigure);
            app.CH1AnaLabel.Text = 'CH';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = '1V';
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'T';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0.01';
            app.Frecuency2SliderLabel = uilabel(app.UIFigure);
            app.Frecuency2SliderLabel.Text = 'Frecuency2';
            app.Frecuency2Slider = uislider(app.UIFigure);
            app.Frecuency2Slider.ValueChangedFcn = createCallbackFcn(app, @Frecuency2SliderValueChanged, true);
            app.FilterTypeListBoxLabel = uilabel(app.UIFigure);
            app.FilterTypeListBoxLabel.Text = 'Filter Type';
            app.FilterTypeListBox = uilistbox(app.UIFigure);
            app.FilterTypeListBox.Items = {'lowpass', 'highpass', 'bandpass', 'stopband'};
            app.FilterTypeListBox.ValueChangedFcn = createCallbackFcn(app, @FilterTypeListBoxValueChanged, true);
            app.FilterTypeListBox.Value = 'lowpass';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sound_Detector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end